classdef user < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        InformationPanel        matlab.ui.container.Panel
        Label                   matlab.ui.control.Label
        MediumListPanel         matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        UITable                 matlab.ui.control.Table
        addmediumDropDown       matlab.ui.control.DropDown
        addmediumDropDownLabel  matlab.ui.control.Label
        CreateButton            matlab.ui.control.Button
    end

    %% 額外屬性 (Private)
    properties (Access = private)
        DialogApp_CreateCube % Dialog box app 
    end
    
    %% 額外屬性 (Public) (For store data)
    properties (Access = public)
        M = MediumList
        % empty table
        T = cell2table(cell(0,2), 'VariableNames', {'Name','Type'})
    end
    
    %% public method
    methods (Access = public)

        %% 從 dialog 呼叫此方法
        function update_from_create(app,object)
            disp("[info]: 已創建一個名為 " + object.name + " 的 " + object.type)
            app.M.add(object)   % 新增 Medium
            app.update_T        % 更新 T 屬性
            app.update_table    % 更新顯示的 table
        end

        %% update Table
        function update_T(app)
            num = length(app.M.list);
            name = cell(num,1);
            type = cell(num,1);
            for ii = 1 : num
                name{ii} = char(app.M.list(ii).name);
                type{ii} = char(app.M.list(ii).type);
            end
            app.T = table(name,type);
        end
    end
    
    %% private method
    methods (Access = private)
        %% 更新 medium list table
        function update_table(app)
            app.UITable.Data = table2cell(app.T);
        end

        %% 更新 info panel (by 選擇 table 不同 cell)
        function update_info(app,str)
            app.TextArea.Value = str;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.CreateButton.Enable = "off";
    
            % Call dialog box with input values
            disp("[info]: 創建 Cube...")
            app.DialogApp_CreateCube = create_cube(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           % Delete all apps
           delete(app.DialogApp_CreateCube)
           delete(app)    
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            disp(indices)
            update_info(app, formattedDisplayText(app.M(indices(1)))) % 顯示該 Medium 資訊
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MediumListPanel = uipanel(app.GridLayout);
            app.MediumListPanel.Title = 'Medium List';
            app.GridLayout2 = uigridlayout(app.MediumListPanel);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.addmediumDropDownLabel = uilabel(app.GridLayout2);
            app.addmediumDropDownLabel.Text = 'add medium';
            app.addmediumDropDown = uidropdown(app.GridLayout2);
            app.addmediumDropDown.Items = {'Cube', 'Lens', 'GP'};
            app.addmediumDropDown.Value = 'Cube';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Name'; 'Type'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.Label = uilabel(app.InformationPanel);
            app.Label.Interpreter = 'latex';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = user

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end